classdef getArcs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        undistortImageFromSingleViewUIFigure  matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        openfolderMenu          matlab.ui.container.Menu
        openimageMenu           matlab.ui.container.Menu
        saveMenu                matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        imageListsListBox       matlab.ui.control.ListBox
        imageListsListBoxLabel  matlab.ui.control.Label
        drawButton              matlab.ui.control.Button
        nextButton              matlab.ui.control.Button
        previousButton          matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        allImageFullLists % Description
        currSelectImage
        hImage
        M = table([],{},[],VariableNames=["arcs","points","resnorm"]);

        % undistort mapX mapY
        mapX
        mapY
    end

    methods (Access = private)

        function results = undistortImageWarpper(app)
            if size(app.M,1)>=3
                distortedImage = app.currSelectImage;
                [undistortImage,app.mapX,app.mapY] = robustRectifyImage(distortedImage,app.M);
                imshow(undistortImage,Parent=app.UIAxes);
                app.UIAxes.Title.String = "undistort image";
            end
        end


        function allevents(app,src,evt)
            evname = evt.EventName;
            if matches(evname,"DrawingFinished")
                disp("draw finished");
            elseif matches(evname,"DeletingROI")
                disp("deleting ROI")
                findobj(app.UIAxes,Type=AssistedFreehand)
                undistortImageWarpper(app);
            elseif matches(evname,"DrawingStarted")
                disp("DrawingStarted")
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: openfolderMenu
        function openfolderMenuSelected(app, event)

            selpath = uigetdir('/opt_disk2/rd22946/AllDataAndModels/from_tongwenchao/116');
            imds = imageDatastore(selpath,IncludeSubfolders=false,FileExtensions=[".jpg",".png"]);
            imageNames = string(imds.Files);
            [~,fileNames,~] = fileparts(imageNames);
            app.imageListsListBox.Items = fileNames;
            app.allImageFullLists = imageNames;

            app.imageListsListBox.Value = fileNames(1);% able selected show
            app.currSelectImage = imread(imageNames(1));
            app.hImage = imshow(app.currSelectImage,Parent=app.UIAxes);

            load ../data/preSavedData/data_caoji_ok1.mat
            app.M = M;
            app.mapX = mapX;
            app.mapY = mapY;
        end

        % Menu selected function: openimageMenu
        function openimageMenuSelected(app, event)
     
            [file,path] = uigetfile({'*.jpg';'*.png'},...
                'Select image file','./');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            file = string(file);
            app.imageListsListBox.Items = file;
            app.allImageFullLists = string(fullfile(path,file));
            app.imageListsListBox.Value = file(1);% able selected show
            app.currSelectImage = imread(fullfile(path,file));
            app.hImage = imshow(app.currSelectImage,Parent=app.UIAxes);
            app.UIAxes.Title.String = "distorted image";
        end

        % Double-clicked callback: imageListsListBox
        function doubleClickFcn(app, event)
            selectIdx = event.InteractionInformation.Item;
            % selectIdx = find(endsWith(app.allImageFullLists,item));
            app.currSelectImage = imread(app.allImageFullLists(selectIdx));
            app.hImage = imshow(app.currSelectImage,Parent=app.UIAxes);
            app.UIAxes.Title.String = "distorted image";
        end

        % Button pushed function: previousButton
        function previousButtonPushed(app, event)
            if ~isempty(app.allImageFullLists)
                selectIdx = find(matches(app.imageListsListBox.Items,app.imageListsListBox.Value));
                selectIdx = max(1,selectIdx-1);
                app.imageListsListBox.Value = app.imageListsListBox.Items{selectIdx};% able selected show
                app.currSelectImage = imread(app.allImageFullLists(selectIdx));
                app.hImage=imshow(app.currSelectImage,Parent=app.UIAxes);
                app.UIAxes.Title.String = "distorted image";
            end

        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if ~isempty(app.allImageFullLists)
                selectIdx = find(matches(app.imageListsListBox.Items,app.imageListsListBox.Value));
                selectIdx = min(selectIdx+1,numel(app.allImageFullLists));
                app.imageListsListBox.Value = app.imageListsListBox.Items{selectIdx};% able selected show
                app.currSelectImage = imread(app.allImageFullLists(selectIdx));
                app.hImage = imshow(app.currSelectImage,Parent=app.UIAxes);
                app.UIAxes.Title.String = "distorted image";
            end
        end

        % Button pushed function: drawButton
        function drawButtonPushed(app, event)
            % greyImg  = im2gray(app.currSelectImage);
            % BW = edge(greyImg,"canny");
            % app.hImage = imshow(BW,Parent=app.UIAxes);
            % app.UIAxes.Title.String = "distorted image";
            app.hImage = imshow(app.currSelectImage,Parent=app.UIAxes);
            roi = drawassisted(Closed=false,Parent=app.UIAxes,Image=app.hImage,Tag="",Smoothing=8);
            % addlistener(roi,'DeletingROI',@(app,src,evt)allevents(app,src,evt));

            xdata = roi.Position(:,1);
            ydata = roi.Position(:,2);

            currPts = [xdata(:),ydata(:)];
            fitFcn = @(points)robustLeastSquareCircle(points);% test_leastSquareFitCircle.m
            distFcn = @(model, points)((points(:,1)-model(1)).^2+(points(:,2)-model(2)).^2-model(3).^2).^2;
            sampleSize = 3;
            maxDistance = 5000;
            % [modelRansac,inlierIdx] = ransac(currPts,fitFcn,distFcn,sampleSize,maxDistance);
            % if sum(inlierIdx)>=3
            [modelRobust,res] = robustLeastSquareCircle(currPts);
            h = viscircles(app.UIAxes,modelRobust(1:2),modelRobust(3),Color="red");
            app.UIAxes.XLim = [1,size(app.currSelectImage,2)];
            app.UIAxes.YLim = [1,size(app.currSelectImage,1)];
            % delete(h);delete(J)
            A = -2*modelRobust(1);% 2009_(经典_重点_大连理工)A Simple Method of Radial Distortion Correction with Centre of Distortion Estimation
            B = -2*modelRobust(2);
            C = (modelRobust(1))^2+(modelRobust(2))^2-(modelRobust(3))^2;
            app.M(end+1,:) = table([A,B,C],{currPts},res);

            %% undistot current image
            undistortImageWarpper(app);
        end

        % Menu selected function: saveMenu
        function saveMenuSelected(app, event)
            M = app.M;
            mapX = app.mapX;
            mapY = app.mapY;
            save data.mat M mapX mapY
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.undistortImageFromSingleViewUIFigure = uifigure('Visible', 'off');
            app.undistortImageFromSingleViewUIFigure.Name = 'undistort Image From Single View';
            app.FileMenu = uimenu(app.undistortImageFromSingleViewUIFigure);
            app.FileMenu.Text = 'File';
            app.openfolderMenu = uimenu(app.FileMenu);
            app.openfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @openfolderMenuSelected, true);
            app.openfolderMenu.Text = 'open folder';
            app.openimageMenu = uimenu(app.FileMenu);
            app.openimageMenu.MenuSelectedFcn = createCallbackFcn(app, @openimageMenuSelected, true);
            app.openimageMenu.Text = 'open image';
            app.saveMenu = uimenu(app.FileMenu);
            app.saveMenu.MenuSelectedFcn = createCallbackFcn(app, @saveMenuSelected, true);
            app.saveMenu.Text = 'save';
            app.HelpMenu = uimenu(app.undistortImageFromSingleViewUIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.undistortImageFromSingleViewUIFigure);
            title(app.UIAxes, 'distorted image(fisheye)')
            app.previousButton = uibutton(app.undistortImageFromSingleViewUIFigure, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previousButton.Text = 'previous';
            app.nextButton = uibutton(app.undistortImageFromSingleViewUIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.drawButton = uibutton(app.undistortImageFromSingleViewUIFigure, 'push');
            app.drawButton.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = 'draw';
            app.imageListsListBoxLabel = uilabel(app.undistortImageFromSingleViewUIFigure);
            app.imageListsListBoxLabel.Text = 'image  Lists';
            app.imageListsListBox = uilistbox(app.undistortImageFromSingleViewUIFigure);
            app.imageListsListBox.Items = {};
            app.imageListsListBox.DoubleClickedFcn = createCallbackFcn(app, @doubleClickFcn, true);
            app.imageListsListBox.Value = {};
            % Show the figure after all components are created
            app.undistortImageFromSingleViewUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getArcs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.undistortImageFromSingleViewUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.undistortImageFromSingleViewUIFigure)
        end
    end
end